classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Ac              matlab.ui.control.Spinner
        AcSpinnerLabel  matlab.ui.control.Label
        Am              matlab.ui.control.Spinner
        AmSpinnerLabel  matlab.ui.control.Label
        fm              matlab.ui.control.Slider
        fmSliderLabel   matlab.ui.control.Label
        AMMOD           matlab.ui.control.Button
        fc              matlab.ui.control.Slider
        fcLabel         matlab.ui.control.Label
        fs              matlab.ui.control.Slider
        fsLabel         matlab.ui.control.Label
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t
        m
        x
        y
        Ym
        Yc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function fmValueChanged(app, event)

            
        end

        % Value changing function: fs
        function fsValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: fc
        function fcValueChanging(app, event)
            changingValue = event.Value;   
            
        end

        % Button pushed function: AMMOD
        function AMMODPushed(app, event)
            t = (0 : 1 /app.fs.Value : app.fs.Value*10);
            Ym=app.Am.Value*cos(2*180*app.fm.Value.*t); 
            Yc= app.Ac.Value*cos(2*180*app.fc.Value.*t); 
            m=app.Am.Value/app.Ac.Value; 
    
            y= app.Ac.Value*(1+m*cos(2*180*app.fm.Value*t)).*cos(2*180*app.fc.Value.*t); 
           plot (app.UIAxes3,Ym); 
           plot (app.UIAxes2,Yc); 
           plot (app.UIAxes,y); 
        end

        % Value changing function: fm
        function fmValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: Ac
        function AcValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: Am
        function AmValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'c(t)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'm(t)')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs';
            app.fs = uislider(app.UIFigure);
            app.fs.ValueChangingFcn = createCallbackFcn(app, @fsValueChanging, true);
            app.fcLabel = uilabel(app.UIFigure);
            app.fcLabel.Text = 'fc';
            app.fc = uislider(app.UIFigure);
            app.fc.ValueChangingFcn = createCallbackFcn(app, @fcValueChanging, true);
            app.AMMOD = uibutton(app.UIFigure, 'push');
            app.AMMOD.ButtonPushedFcn = createCallbackFcn(app, @AMMODPushed, true);
            app.AMMOD.Text = {'AM MOD'; ''};
            app.fmSliderLabel = uilabel(app.UIFigure);
            app.fmSliderLabel.Text = 'fm';
            app.fm = uislider(app.UIFigure);
            app.fm.ValueChangingFcn = createCallbackFcn(app, @fmValueChanging, true);
            app.AmSpinnerLabel = uilabel(app.UIFigure);
            app.AmSpinnerLabel.Text = 'Am';
            app.Am = uispinner(app.UIFigure);
            app.Am.ValueChangingFcn = createCallbackFcn(app, @AmValueChanging, true);
            app.AcSpinnerLabel = uilabel(app.UIFigure);
            app.AcSpinnerLabel.Text = 'Ac';
            app.Ac = uispinner(app.UIFigure);
            app.Ac.ValueChangingFcn = createCallbackFcn(app, @AcValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end